classdef NR5G < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DLS5GUIFigure                  matlab.ui.Figure
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        ParametersTab                  matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        Actions                        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        AbouttheAppLabel               matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        LoadFileLabel                  matlab.ui.control.Label
        TimeSimulationLabel            matlab.ui.control.Label
        SimpleSimulationLabel          matlab.ui.control.Label
        ExportParametersCheckBox       matlab.ui.control.CheckBox
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        GridLayout6                    matlab.ui.container.GridLayout
        FramesLabel                    matlab.ui.control.Label
        FramesNumberEditField          matlab.ui.control.EditField
        DataDropDown                   matlab.ui.control.DropDown
        DataDropDownLabel              matlab.ui.control.Label
        PanelSpeed                     matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        RangeVelLabel                  matlab.ui.control.Label
        EditField_vel_init             matlab.ui.control.NumericEditField
        RangeSlider_Vel                matlab.ui.control.RangeSlider
        ValueLabelVel                  matlab.ui.control.Label
        TypeVelocityDropDown           matlab.ui.control.DropDown
        TypeDropDownLabel_2            matlab.ui.control.Label
        PanelSNR                       matlab.ui.container.Panel
        GridSNR                        matlab.ui.container.GridLayout
        RangeSNRLabel                  matlab.ui.control.Label
        TypeSNRDropDown                matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        EditField_SNRValue             matlab.ui.control.NumericEditField
        ValueLabelSNR                  matlab.ui.control.Label
        RangeSliderSNR                 matlab.ui.control.RangeSlider
        PanelModels                    matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        phase_comp_model               matlab.ui.control.DropDown
        CNNPhaseCompensationCheckBox   matlab.ui.control.CheckBox
        Denoising2CheckBox             matlab.ui.control.CheckBox
        Denoising1CheckBox             matlab.ui.control.CheckBox
        PracticalCheckBox              matlab.ui.control.CheckBox
        AutoencoderCheckBox            matlab.ui.control.CheckBox
        CNNModel2DropDown              matlab.ui.control.DropDown
        CNNModel2CheckBox              matlab.ui.control.CheckBox
        CNNModel1DropDown              matlab.ui.control.DropDown
        CNNModel1CheckBox              matlab.ui.control.CheckBox
        PanelChannelConfig             matlab.ui.container.Panel
        GridChannel                    matlab.ui.container.GridLayout
        ModulationDropDown             matlab.ui.control.DropDown
        ModulationDropDownLabel        matlab.ui.control.Label
        ChannelProfileDropDown         matlab.ui.control.DropDown
        ChannelProfileDropDownLabel    matlab.ui.control.Label
        PanelTitle                     matlab.ui.container.Panel
        Metricas                       matlab.ui.container.Tab
        GridMetricas                   matlab.ui.container.GridLayout
        GridDistribution               matlab.ui.container.GridLayout
        MultiplePlotPanel              matlab.ui.container.Panel
        GridMultiple                   matlab.ui.container.GridLayout
        NormalizedCheckBox             matlab.ui.control.CheckBox
        ClearTIME                      matlab.ui.control.Button
        GraphTIME                      matlab.ui.control.Button
        ClearMSE                       matlab.ui.control.Button
        GraphMSE                       matlab.ui.control.Button
        ClearEVM                       matlab.ui.control.Button
        GraphEVM                       matlab.ui.control.Button
        ClearBER                       matlab.ui.control.Button
        GraphBER                       matlab.ui.control.Button
        EVM                            matlab.ui.control.UIAxes
        BER                            matlab.ui.control.UIAxes
        MSE                            matlab.ui.control.UIAxes
        TIME                           matlab.ui.control.UIAxes
        Unique                         matlab.ui.control.UIAxes
        GridOptions                    matlab.ui.container.GridLayout
        ClearUnique                    matlab.ui.control.Button
        GraphUnique                    matlab.ui.control.Button
        DropDown_2                     matlab.ui.control.DropDown
        StyleGraphLabel                matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        SelectFileDropDown             matlab.ui.control.DropDown
        SelectFileLabel                matlab.ui.control.Label
        GraphTypeDropDown              matlab.ui.control.DropDown
        GraphTypeLabel                 matlab.ui.control.Label
        GraphicsPanel                  matlab.ui.container.Panel
        TimeSimulationTab              matlab.ui.container.Tab
        GridTimeSimulation             matlab.ui.container.GridLayout
        GridDistribution2              matlab.ui.container.GridLayout
        PanelActions                   matlab.ui.container.Panel
        GridPanelActionDistribution    matlab.ui.container.GridLayout
        GridInfoDistribution           matlab.ui.container.GridLayout
        GridTableInfo                  matlab.ui.container.GridLayout
        Model3_TimeLabel               matlab.ui.control.Label
        Model2_TimeLabel               matlab.ui.control.Label
        Model3_EVMLabel                matlab.ui.control.Label
        Model2_EVMLabel                matlab.ui.control.Label
        Model1_TimeLabel               matlab.ui.control.Label
        Model1_EVMLabel                matlab.ui.control.Label
        Model3_MSELabel                matlab.ui.control.Label
        Model2_MSELabel                matlab.ui.control.Label
        Model1_MSELabel                matlab.ui.control.Label
        Model3Label                    matlab.ui.control.Label
        Model2Label                    matlab.ui.control.Label
        Model1Label                    matlab.ui.control.Label
        TimemsLabel                    matlab.ui.control.Label
        EVMLabel                       matlab.ui.control.Label
        MSELabel                       matlab.ui.control.Label
        GridTimeActions                matlab.ui.container.GridLayout
        Plot_Constellations            matlab.ui.control.Button
        NormalizedTimeCheckBox         matlab.ui.control.CheckBox
        ShowTableButton                matlab.ui.control.Button
        Grafica3DropDown               matlab.ui.control.DropDown
        Graph3DropDownLabel            matlab.ui.control.Label
        Grafica2DropDown               matlab.ui.control.DropDown
        Graph2DropDownLabel            matlab.ui.control.Label
        Grafica1DropDown               matlab.ui.control.DropDown
        Graph1DropDownLabel            matlab.ui.control.Label
        GridSymbolInfo                 matlab.ui.container.GridLayout
        SymbolsButton                  matlab.ui.control.StateButton
        EditFieldSymbol                matlab.ui.control.NumericEditField
        TimeLabel                      matlab.ui.control.Label
        Symbol                         matlab.ui.control.UIAxes
        PanelPlots                     matlab.ui.container.Panel
        GridPlots                      matlab.ui.container.GridLayout
        Graph3                         matlab.ui.control.UIAxes
        Graph2                         matlab.ui.control.UIAxes
        Graph1                         matlab.ui.control.UIAxes
        Titulo_TimeLabel               matlab.ui.control.Label
        RealTimeTab                    matlab.ui.container.Tab
        GridRealTime                   matlab.ui.container.GridLayout
        GridRealTimeDistribution       matlab.ui.container.GridLayout
        GridRealTimeActions            matlab.ui.container.GridLayout
        PhaseCompensation              matlab.ui.control.CheckBox
        DatatoplotDropDown             matlab.ui.control.DropDown
        DatatoplotDropDownLabel        matlab.ui.control.Label
        SpeedkmhKnobLabel              matlab.ui.control.Label
        SpeedkmhKnob                   matlab.ui.control.Knob
        SNRdBKnobLabel                 matlab.ui.control.Label
        SNRdBKnob                      matlab.ui.control.Knob
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        ModulationDropDown_2           matlab.ui.control.DropDown
        ModulationDropDown_2Label      matlab.ui.control.Label
        CNNModel1DropDown_2            matlab.ui.control.DropDown
        CNNModelLabel                  matlab.ui.control.Label
        EstimationTypeDropDown         matlab.ui.control.DropDown
        EstimationTypeDropDownLabel    matlab.ui.control.Label
        DropDown_3                     matlab.ui.control.DropDown
        ChannelProfileLabel            matlab.ui.control.Label
        GridPlotRealTime               matlab.ui.container.GridLayout
        Graph1_2                       matlab.ui.control.UIAxes
        Graph1_4                       matlab.ui.control.UIAxes
        Graph1_3                       matlab.ui.control.UIAxes
        RealTimeChannelVariationLabel  matlab.ui.control.Label
        External_plots                 matlab.ui.container.Tab
    end


    properties (Access = private)

        
    end

    properties (Access = public)
        Pam_sim = [];% Parametros de la simulacion
        Parameters =[];


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Inicializacion de todas las variables
            set(app.RangeSNRLabel, "Text", "")
            app.Pam_sim.marks = ["o","pentagram","square","diamond","^","+","hexagram",".","x","_","|","square","diamond","^","v","<",">","*"];
            app.Pam_sim.lines = ["-","--",":","-."];
            app.Pam_sim.colors = ["r","b","g","m","c","y","k"];

            %Initialization data 
            app = Init_Parameters(app);
                
          
        end

        % Button pushed function: GraphBER
        function GraphBERPushed(app, event)
            hold(app.BER,"on");
            try
                label = app.Parameters.models;
                label{1,2} = 'Ideal';
                %% ----------------------- BER Real Time----------------------------------
                if length(app.Parameters.SNR_Recorridas ) ~= length(app.Parameters.Mat_Lineal_BER)
                    app.Parameters.SNR_Recorridas = app.Parameters.SNR_Recorridas(2:end);
                end

                for i = 1:length(app.Parameters.models)
                    semilogy(app.BER,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_BER"),app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth)
                end
                legend(app.BER,label, 'Color','w');

            catch error
                message = error.message;
                id = error.identifier
                if (strcmp(id,'MATLAB:nonExistentField'))
                    uialert(app.DLS5GUIFigure,['There is not data to plot '],"Invalid File","Icon","info");
                end

            end
        end

        % Size changed function: Metricas
        function MetricasSizeChanged(app, event)
            %poition = app.Metricas.Position;

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

        end

        % Close request function: DLS5GUIFigure
        function DLS5GUIFigureCloseRequest(app, event)
            delete(app)

        end

        % Button pushed function: ClearBER
        function ClearBERPushed(app, event)
            hold(app.BER,"off");
            cla(app.BER)
            app.BER.XLimMode = "auto";
            app.BER.YLimMode = "auto";
        end

        % Button pushed function: ClearEVM
        function ClearEVMPushed(app, event)
            hold(app.EVM,"off");
            cla(app.EVM)
            app.EVM.XLimMode = "auto";
            app.EVM.YLimMode = "auto";
        end

        % Button pushed function: GraphEVM
        function GraphEVMPushed(app, event)
            try
                label = app.Parameters.models;
                label{1,2} = 'Ideal';
                hold(app.EVM,"on");
                %% ----------------------- EVM Real Time----------------------------------
                for i = 1:length(app.Parameters.models)
                    plot(app.EVM,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_EVM"),app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth)
                end
                legend(app.EVM,label, 'Color', 'w')
            catch error
                message = error.message;
                id = error.identifier
                if (strcmp(id,'MATLAB:nonExistentField'))
                    uialert(app.DLS5GUIFigure,['There is not data to plot '],"Invalid File","Icon","info");
                end

            end
        end

        % Button pushed function: GraphTIME
        function GraphTIMEPushed(app, event)
            
            try
                label = app.Parameters.models;
                label{1,2} = 'Ideal';
                hold(app.TIME,"on");
                %% ----------------------- Time----------------------------------
                if app.NormalizedCheckBox.Value
                    
                    for i = 1:length(app.Parameters.models)
                        plot(app.TIME,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_Time")./app.Parameters.Mat_Perfect_Time,app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth);
                    end
                else
                    for i = 1:length(app.Parameters.models)
                        plot(app.TIME,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_Time")./1e-3,app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth);
                    end
                end
                
                legend(app.TIME,label, 'Color', 'w')
            catch error
                message = error.message;
                id = error.identifier
                if (strcmp(id,'MATLAB:nonExistentField'))
                    uialert(app.DLS5GUIFigure,['There is not data to plot '],"Invalid File","Icon","info");
                end

            end


        end

        % Button pushed function: ClearTIME
        function ClearTIMEPushed(app, event)
            hold(app.TIME,"off");
            cla(app.TIME)
            app.TIME.XLimMode = "auto";
            app.TIME.YLimMode = "auto";          
        end

        % Button pushed function: ClearMSE
        function ClearMSEPushed(app, event)
            hold(app.MSE,"off");
            cla(app.MSE)
            app.MSE.XLimMode = "auto";
            app.MSE.YLimMode = "auto";
        end

        % Button pushed function: GraphMSE
        function GraphMSEPushed(app, event)

            try
                label = app.Parameters.models;
                label{1,2} = 'Ideal';
                hold(app.MSE,"on");
                % ----------------------- MSE Real Time----------------------------------
                for i = 1:length(app.Parameters.models)
                    if strcmp(app.Parameters.models{1,i},"Perfect")
                        app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_MSE")  = zeros(size(app.Parameters.("Mat_"+string(app.Parameters.models{1,1})+"_MSE")));
                    end
                    plot(app.MSE,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_MSE"),app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth)
                end
                legend(app.MSE,label, 'Color', 'w')
            catch error
                message = error.message;
                id = error.identifier
                if (strcmp(id,'MATLAB:nonExistentField'))
                    uialert(app.DLS5GUIFigure,['There is not data to plot '],"Invalid File","Icon","info");
                end

            end
        end

        % Drop down opening function: TypeSNRDropDown
        function TypeSNRDropDownOpening3(app, event)
            selection = app.TypeSNRDropDown.Value;
            switch selection
                case "Static"
                    app.Pam_sim.SNR_STATIC = true;
                case "Interval"
                    app.Pam_sim.SNR_STATIC = false;
            end
        end

        % Value changed function: TypeSNRDropDown
        function TypeSNRDropDownValueChanged(app, event)
            selection = app.TypeSNRDropDown.Value;
            switch selection
                case "Static"
                    app.Pam_sim.SNR_STATIC = true;
                    app.RangeSliderSNR.Value = [0,30];
                    app.ValueLabelSNR.Visible = "on";
                    app.EditField_SNRValue.Enable = "on";
                    app.RangeSliderSNR.Enable = "off";
                    app.EditField_SNRValue.Visible = "on";
                    app.RangeSliderSNR.Visible = "off";
                    app.RangeSNRLabel.Visible = "off";
                    app.RangeSNRLabel.Text = "Range: "+ int2str(0) + " - " + int2str(30);
                case "Interval"
                    app.Pam_sim.SNR_STATIC = false;
                    app.ValueLabelSNR.Visible = "off";
                    app.EditField_SNRValue.Enable = "off";
                    app.RangeSliderSNR.Enable = "on";
                    app.EditField_SNRValue.Visible = "off";
                    app.RangeSliderSNR.Visible = "on";
                    app.RangeSNRLabel.Visible = "on";     
            end

        end

        % Value changed function: ModulationDropDown
        function ModulationDropDownValueChanged(app, event)
            value = app.ModulationDropDown.Value;
            switch value
                case "QPSK"
                    app.Pam_sim.Modulation = "QPSK";
                case "16QAM"
                    app.Pam_sim.Modulation = "16QAM";
                case "64QAM"
                    app.Pam_sim.Modulation = "64QAM";
                case "256QAM"
                    app.Pam_sim.Modulation = "256QAM";
            end
        end

        % Value changed function: ChannelProfileDropDown
        function ChannelProfileDropDownValueChanged(app, event)
            value = app.ChannelProfileDropDown.Value;
            switch value
                case "TDL-A"
                    app.Pam_sim.Channel = "TDL-A";
                case "TDL-B"
                    app.Pam_sim.Channel = "TDL-B";
                case "TDL-C"
                    app.Pam_sim.Channel = "TDL-C";
                case "TDL-D"
                    app.Pam_sim.Channel = "TDL-D";
                case "TDL-E"
                    app.Pam_sim.Channel = "TDL-E";

            end

        end

        % Value changed function: CNNModel1CheckBox
        function CNNModel1CheckBoxValueChanged(app, event)

            sel = app.CNNModel1CheckBox.Value;
            app.Pam_sim.CNNEstimation = sel ;
            if sel
                app.CNNModel1DropDown.Visible = "on";
            else
                app.CNNModel1DropDown.Visible = "off";
            end
        end

        % Value changed function: CNNModel2CheckBox
        function CNNModel2CheckBoxValueChanged(app, event)

            sel = app.CNNModel2CheckBox.Value;
            app.Pam_sim.CNNEstimation_2 = sel ;
            if sel
                app.CNNModel2DropDown.Visible = "on";
            else
                app.CNNModel2DropDown.Visible = "off";
            end

        end

        % Value changed function: Denoising2CheckBox
        function Denoising2CheckBoxValueChanged(app, event)
            app.Pam_sim.Denoising_Estimation_resta = app.Denoising2CheckBox.Value;

        end

        % Value changed function: Denoising1CheckBox
        function Denoising1CheckBoxValueChanged(app, event)
            app.Pam_sim.Denoising_Estimation = app.Denoising1CheckBox.Value;

        end

        % Value changed function: PracticalCheckBox
        function PracticalCheckBoxValueChanged(app, event)
            app.Pam_sim.EstimacionPractica = app.PracticalCheckBox.Value;

        end

        % Value changed function: AutoencoderCheckBox
        function AutoencoderCheckBoxValueChanged(app, event)
            app.Pam_sim.Autoencoder_Estimation = app.AutoencoderCheckBox.Value;
        end

        % Callback function
        function DropDownOpening(app, event)

        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            switch value
                case "Frames"
                    app.FramesLabel.Text = "Frames";
                    app.FramesLabel.Visible ="on";
                    app.Image3.Enable = "off";
                    app.FramesNumberEditField.Visible = "on";
                    app.Pam_sim.Transmision_IMG = false;
                    app.Pam_sim.Transmision_Bits = false;
                case "Bits"
                    app.FramesLabel.Text = "Bits";
                    app.FramesLabel.Visible ="on";
                    app.Image3.Enable = "off";
                    app.FramesNumberEditField.Visible = "on";
                    app.Pam_sim.Transmision_IMG = false;
                    app.Pam_sim.Transmision_Bits = true;
                case "Image"
                    app.FramesLabel.Visible ="off";
                    app.Image3.Enable = "on";
                    app.FramesNumberEditField.Visible = "off";
                    app.Image3.Visible = "on";
                    app.Pam_sim.Transmision_IMG = true;
                    app.Pam_sim.Transmision_Bits = false;
                otherwise
            end
        end

        % Callback function
        function DataDropDownOpening(app, event)

        end

        % Value changed function: FramesNumberEditField
        function FramesNumberEditFieldValueChanged(app, event)
            value = str2double(app.FramesNumberEditField.Value);
            if value ==0
                uialert(app.DLS5GUIFigure,"Number must be higher than 0","Invalid File");
            end
            switch app.DataDropDown.Value
                case "Frames"
                    app.Pam_sim.Num_Frames = value;
                case "Bits"
                    app.Pam_sim.Bits_Deseados = value;
                otherwise
            end

        end

        % Callback function
        function TypeSNRDropDownOpening2(app, event)

        end

        % Callback function
        function Knob_SNRValueChanged(app, event)

        end

        % Value changed function: EditField_SNRValue
        function EditField_SNRValueValueChanged(app, event)
            value = app.EditField_SNRValue.Value;
            app.Pam_sim.SNR_Init = value;
        end

        % Callback function
        function EditField_SNR_stepValueChanged(app, event)

        end

        % Callback function
        function Knob_VelValueChanged(app, event)

        end

        % Callback function
        function EditField_vel_initValueChanged(app, event)
            value = app.EditField_vel_init.Value;
            app.Pam_sim.Vel_init = value;
        end

        % Callback function
        function EditField_vel_stepValueChanged(app, event)

        end

        % Callback function
        function TypeVelocityDropDownValueChanged(app, event)
            value = app.TypeVelocityDropDown.Value;
            switch value
                case "Static"
                    app.Pam_sim.Vel_sim_Estatic = true;
                    app.RangeSlider_Vel.Value = [0,120];
                    app.ValueLabelVel.Visible = "on";
                    app.EditField_vel_init.Visible = "on";
                    app.RangeSlider_Vel.Visible = "off";
                    app.RangeVelLabel.Visible = "off";
                    app.RangeVelLabel.Text = "Range: "+int2str(0) + " - " + int2str(120);
                case "Interval"
                    app.Pam_sim.Vel_sim_Estatic = false;
                    app.ValueLabelVel.Visible = "off";
                    app.EditField_vel_init.Visible = "off";
                    app.RangeSlider_Vel.Visible = "on";
                    app.RangeSlider_Vel.Enable = 'on';
                    app.RangeVelLabel.Visible = "on";
                otherwise             
            end        
        end

        % Value changed function: CNNModel2DropDown
        function CNNModel2DropDownValueChanged(app, event)
            app.Pam_sim.CNNmodel_2 = app.CNNModel2DropDown.Value;
        end

        % Value changed function: CNNModel1DropDown
        function CNNModel1DropDownValueChanged(app, event)
            app.Pam_sim.CNNmodel_1 = app.CNNModel1DropDown.Value;
        end

        % Value changed function: CNNPhaseCompensationCheckBox
        function CNNPhaseCompensationCheckBoxValueChanged(app, event)

            app.Pam_sim.Compensacion_Fase = app.CNNPhaseCompensationCheckBox.Value;
            if app.Pam_sim.Compensacion_Fase
                app.Pam_sim.Constelations = true;
                checkboxes = findall(app.PanelModels,'type','uicheckbox');
                select_CheckBox = {};
                
                for i=1:numel(checkboxes)
                    if get(checkboxes(i), "Value") == 1
                        if ~strcmp(checkboxes(i).Text,'Phase Com')
                            select_CheckBox{end+1} = checkboxes(i).Text;
                        end
                        
                    end
                end
             
                app.phase_comp_model.Items = string(select_CheckBox);
                app.phase_comp_model.Visible = true;
                app.phase_comp_model.Enable = "on";
            else
                app.Pam_sim.Constelations = false;
                app.phase_comp_model.Visible = "off";
            end

        end

        % Value changed function: ExportParametersCheckBox
        function ExportParametersCheckBoxValueChanged(app, event)
            app.Pam_sim.Save_Variables  = app.ExportParametersCheckBox.Value;
        end

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
            value = app.GraphTypeDropDown.Value;
            switch value
                case "Current Simulation"
                    app.SelectFileDropDown.Visible = "off";
                    app.SelectFileLabel.Visible = "off";
                    app.Parameters = app.Pam_sim;
                case "Load File"
                    Files = dir(fullfile("Outputs/",'**/*.mat*'));
                    numfiles = length(Files);
                    mydata = cell(1, numfiles);
                    for k = 1:numfiles
                        mydata{k} = Files(k).name;
                    end
                    app.SelectFileDropDown.Visible = "on";
                    app.SelectFileLabel.Visible = "on";
                    app.SelectFileDropDown.Items = mydata;
                  
            end
        end

        % Value changed function: SelectFileDropDown
        function SelectFileDropDownValueChanged(app, event)
            try
                value = app.SelectFileDropDown.Value;
                app.Parameters = load(value).Parameters;
                uialert(app.DLS5GUIFigure,[value," Uploaded"],"Matrix","Icon","success");
            catch error
                uialert(app.DLS5GUIFigure,['  There was an error! The message was:   ',error.message, ' the identifier was: ',error.identifier,],"Invalid File","Icon","error");
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged2(app, event)
            value = app.DropDown.Value;
            switch value
                case "Unique"
                    ClearBERPushed(app, event);
                    ClearEVMPushed(app, event);
                    ClearMSEPushed(app, event);
                    ClearTIMEPushed(app, event);
                    legend(app.BER,"off", 'Color','w');
                    legend(app.MSE,"off", 'Color','w');
                    legend(app.EVM,"off", Color='w');
                    legend(app.TIME,"off",'Color','w');
                    
                    app.MultiplePlotPanel.Visible = 'on';
                    app.GridMultiple.Visible = 'on';
                    app.GraphBER.Visible = "off";
                    app.GraphTIME.Visible = "off";
                    app.GraphMSE.Visible = "off";
                    app.GraphEVM.Visible = "off";

                    app.ClearBER.Visible = "off";
                    app.ClearTIME.Visible = "off";
                    app.ClearMSE.Visible = "off";
                    app.ClearEVM.Visible = "off";

                    app.BER.Visible  = "off";
                    app.TIME.Visible = "off";
                    app.MSE.Visible  = "off";
                    app.EVM.Visible  = "off";
                    
                    app.ClearUnique.Visible = "on";
                    app.GraphUnique.Visible = "on";
                    app.Unique.Visible = "on";
                    app.DropDown_2.Visible = "on";
 
                case "Multiple"
                    ClearUniqueButtonPushed(app, event);
                    legend(app.Unique,"off", 'Color', 'w');
                    app.ClearUnique.Visible = "off";
                    app.GraphUnique.Visible = "off";
                    app.Unique.Visible = "off";
                    app.DropDown_2.Visible = "off";

                    app.GraphBER.Visible = "on";
                    app.GraphTIME.Visible = "on";
                    app.GraphMSE.Visible = "on";
                    app.GraphEVM.Visible = "on";

                    app.ClearBER.Visible = "on";
                    app.ClearTIME.Visible = "on";
                    app.ClearMSE.Visible = "on";
                    app.ClearEVM.Visible = "on";

                    app.BER.Visible  = "on";
                    app.TIME.Visible = "on";
                    app.MSE.Visible  = "on";
                    app.EVM.Visible  = "on";
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            ClearUniqueButtonPushed(app, event);
            legend(app.Unique,"off");
             value = app.DropDown_2.Value;
            switch value
                case "BER vs SNR"
                    app.Pam_sim.Graph_type = "BER";

                    app.Unique.Title.String = "Bit Error Rate";
                    app.Unique.YLabel.String = "BER";
                    app.Unique.XLabel.String = "SNR(dB)";
                case "EVM vs SNR"
                    app.Pam_sim.Graph_type = "EVM";

                    app.Unique.Title.String = "Error Vector Machine";
                    app.Unique.YLabel.String = "EVM(%)";
                    app.Unique.XLabel.String = "SNR(dB)";
                case "MSE vs SNR"
                    app.Pam_sim.Graph_type = "MSE";
                    app.Unique.Title.String = "Mean Square Error";
                    app.Unique.YLabel.String = "MSE";
                    app.Unique.XLabel.String = "SNR(dB)";
                case "Time vs SNR"
                    app.Pam_sim.Graph_type = "Time";
                    app.Unique.YLabel.String = "Time(ms)";
                    app.Unique.XLabel.String = "SNR(dB)";
                case "EVM VS BER VS SNR"
                    app.Pam_sim.Graph_type = "Time";
                    app.Unique.XLabel.String = "SNR(dB)";
            end
            
        end

        % Button pushed function: GraphUnique
        function GraphUniqueButtonPushed(app, event)
            try
            
                if  string(app.GraphTypeDropDown.Value) == "Current Simulation"
                    app.Parameters = app.Pam_sim;
                end
            % app.Pam_sim.Styles 
            % app.Pam_sim.colors_plot
                hold(app.Unique,"on");

                if length(app.Parameters.SNR_Recorridas ) ~= length(app.Parameters.Mat_Lineal_BER)
                    app.Parameters.SNR_Recorridas = app.Parameters.SNR_Recorridas(2:end);
                end
                label = app.Parameters.models;
                label{1,2} = 'Ideal';

                switch app.Pam_sim.Graph_type
                    case "BER"
                        app.Unique.YScale = "log";
                        for i = 1:length(app.Parameters.models)

                            semilogy(app.Unique,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_BER"),app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth);
                        end

                    case "EVM"
                        app.Unique.YScale = "linear";
                        for i = 1:length(app.Parameters.models)
                            plot(app.Unique,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_EVM"),app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth);
                        end
                    case "MSE"
                        app.Unique.YScale = "linear";
                        for i = 1:length(app.Parameters.models)
                            if strcmp(app.Parameters.models{1,i},"Perfect")
                                app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_MSE") = zeros(size(app.Parameters.("Mat_"+string(app.Parameters.models{1,1})+"_MSE")));
                            end
                            plot(app.Unique,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_MSE"),app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth);
                        end
                    case "Time"
                        app.Unique.YScale = "linear";

                        %% ----------------------- Time----------------------------------
                        if app.NormalizedCheckBox.Value

                            for i = 1:length(app.Parameters.models)
                                plot(app.Unique,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_Time")./app.Parameters.Mat_Perfect_Time,app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth);
                            end
                        else
                            for i = 1:length(app.Parameters.models)
                                plot(app.Unique,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_Time")./1e-3,app.Pam_sim.Styles(i),Color=app.Pam_sim.colors_plot(i),LineWidth=app.Pam_sim.LineWidth);
                            end
                        end


                end

                legend(app.Unique,label, 'Color','w')
            catch error
                message = error.message;
                id = error.identifier;
                if (strcmp(id,'MATLAB:nonExistentField'))
                    uialert(app.DLS5GUIFigure,['There is not data to plot '],"Invalid File","Icon","info");
                end

            end
        end

        % Button pushed function: ClearUnique
        function ClearUniqueButtonPushed(app, event)
            hold(app.Unique,"off");
            cla(app.Unique)
            app.Unique.XLimMode = "auto";
            app.Unique.YLimMode = "auto";
        end

        % Callback function
        function TimeSimulationCheckBoxValueChanged(app, event)
            app.Pam_sim.Time_Simulation = app.TimeSimulationCheckBox.Value;
            
        end

        % Callback function
        function ShowTableButtonPushed(app, event)
            %Simulation time 
                %app.Label_2.Visible = "on";
                % app.RealTimeChannelVariationLabel.Visible = "on";

                app.Model1_TimeLabel.Visible = "on";
                app.Model2_TimeLabel.Visible = "on";
                app.Model3_TimeLabel.Visible = "on";

                app.Model1_EVMLabel.Visible = "on";
                app.Model2_EVMLabel.Visible = "on";
                app.Model3_EVMLabel.Visible = "on";

                app.Model1_MSELabel.Visible = "on";
                app.Model2_MSELabel.Visible = "on";
                app.Model3_MSELabel.Visible = "on";

                app.Model1Label.Visible = "on";
                app.Model2Label.Visible = "on";
                app.Model3Label.Visible= "on";

                app.TimemsLabel.Visible = "on";
                app.EVMLabel.Visible = "on";
                app.MSELabel.Visible = "on";

                
            %app = (app);


        end

        % Callback function
        function Grafica1DropDownValueChanged(app, event)
            app.Pam_sim.Grap1_type = app.Grafica1DropDown.Value;
            app.Model1Label.Text = app.Pam_sim.Grap1_type;
            
            switch app.Pam_sim.Grap1_type
                case "CNN"
                    app.Graph1.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph1.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph1.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph1.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph1.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph1.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                     app.Graph1.Title.String = "Interpolation Model"  ;
            end


        end

        % Callback function
        function Grafica2DropDownValueChanged(app, event)
            app.Pam_sim.Grap2_type = app.Grafica2DropDown.Value;
            app.Model2Label.Text = app.Pam_sim.Grap2_type;
            
            switch app.Pam_sim.Grap2_type
                case "CNN"
                    app.Graph2.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph2.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph2.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph2.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph2.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph2.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                    app.Graph2.Title.String = "Interpolation Model"  ;
            end
        end

        % Callback function
        function Grafica3DropDownValueChanged(app, event)
            app.Pam_sim.Grap3_type = app.Grafica3DropDown.Value;
            app.Model3Label.Text = app.Pam_sim.Grap3_type;
            
            switch app.Pam_sim.Grap3_type
                case "CNN"
                    app.Graph3.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph3.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph3.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph3.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph3.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph3.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                    app.Graph3.Title.String = "Interpolation Model"  ;
            end
            
        end

        % Value changed function: CNNModel1DropDown_2
        function CNNModel1DropDown_2ValueChanged(app, event)
            value = app.CNNModel1DropDown_2.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            try
            
            %% ------------------- SIMULATION CONFIGURATION ----------------------------
            %Get parameters from UI
            app.Pam_sim.Stop_RealTime = false;
            app.Pam_sim.Indice_Ranura = 0;
            app.Pam_sim.Grilla_Slots = [];
            app.Pam_sim.EVM = [];
            app.Pam_sim.BER = [];
            app.Pam_sim.TIME = [];
            app.Pam_sim.TIME_PERFECT = [];
            app.Pam_sim.slot  = [];

            % app.Pam_sim.marks = ["o","pentagram","square","diamond","^","+","hexagram",".","x","_","|","square","diamond","^","v","<",">","*"];
            % app.Pam_sim.lines = ["-","--",":","-."];
            % app.Pam_sim.colors = ["r","b","g","m","c","y","k"]
            % Canales 5 ,  6 Redes + 8 modelos , Modulaciones 4
            % 
            app.Pam_sim.Actual_color = ""; 
            app.Pam_sim.Actual_mark =  ""; 
            app.Pam_sim.Actual_line = "";

            while ~app.Pam_sim.Stop_RealTime
                app.Pam_sim.Actual_color = "";
                app.Pam_sim.Actual_mark =  "";
                app.Pam_sim.Actual_line = "";

                drawnow
                app.Pam_sim.SNR = app.SNRdBKnob.Value;
                app.Pam_sim.Modulation = app.ModulationDropDown_2.Value;
                app.Pam_sim.Channel = app.DropDown_3.Value;
                app.Pam_sim.User_Velocity = app.SpeedkmhKnob.Value;
                app.Pam_sim.CNNmodel_1 = app.CNNModel1DropDown_2.Value;
                app.Pam_sim.Compensacion_Fase = app.PhaseCompensation.Value;

                app.Pam_sim.models ={};
                switch app.EstimationTypeDropDown.Value
                    case "Autoencoder"
                        app.Pam_sim.CNNEstimation = false;
                        app.Pam_sim.Autoencoder_Estimation = true;
                        app.Pam_sim.EstimacionPractica = false;
                        app.Pam_sim.Denoising_Estimation = false; 
                        app.Pam_sim.Denoising_Estimation_resta = false;
                        app.CNNModelLabel.Visible = "off";
                        app.CNNModel1DropDown_2.Visible = "off";
                        app.PhaseCompensation.Visible = "on";

                        app.Pam_sim.Model_Phasecomp = app.EstimationTypeDropDown.Value;
                        app.Pam_sim.Actual_color = app.Pam_sim.colors(1); 
                        
   
                    case "CNN"
                        app.Pam_sim.CNNEstimation = true;
                        app.Pam_sim.Autoencoder_Estimation = false;
                        app.Pam_sim.EstimacionPractica = false;
                        app.Pam_sim.Denoising_Estimation = false; 
                        app.Pam_sim.Denoising_Estimation_resta = false;
                        app.CNNModelLabel.Visible = "on";
                        app.CNNModel1DropDown_2.Visible = "on";

                        app.PhaseCompensation.Visible = "on";
                        app.Pam_sim.Model_Phasecomp = app.EstimationTypeDropDown.Value;
                        app.Pam_sim.Actual_color = app.Pam_sim.colors(2); 
   
                    case "Denoising"
                        app.Pam_sim.CNNEstimation = false;
                        app.Pam_sim.Autoencoder_Estimation = false;
                        app.Pam_sim.EstimacionPractica = false;
                        app.Pam_sim.Denoising_Estimation = true;
                        app.Pam_sim.Denoising_Estimation_resta = false;
                        app.CNNModelLabel.Visible = "off";
                        app.CNNModel1DropDown_2.Visible = "off";
                        app.PhaseCompensation.Visible = "on";

                        app.Pam_sim.Model_Phasecomp = app.EstimationTypeDropDown.Value;
                        app.Pam_sim.Actual_color = app.Pam_sim.colors(3); 
                    case "Denoising_2"
                        app.Pam_sim.CNNEstimation = false;
                        app.Pam_sim.Autoencoder_Estimation = false;
                        app.Pam_sim.EstimacionPractica = false;
                        app.Pam_sim.Denoising_Estimation = false;
                        app.Pam_sim.Denoising_Estimation_resta = true;
                        app.CNNModelLabel.Visible = "off";
                        app.CNNModel1DropDown_2.Visible = "off";
                        app.PhaseCompensation.Visible = "on";

                        app.Pam_sim.Model_Phasecomp = app.EstimationTypeDropDown.Value;
                        app.Pam_sim.Actual_color = app.Pam_sim.colors(3); 

                    case "Practical"

                        app.Pam_sim.CNNEstimation = false;
                        app.Pam_sim.Autoencoder_Estimation = false;
                        app.Pam_sim.EstimacionPractica = true;
                        app.Pam_sim.Denoising_Estimation = false;
                        app.Pam_sim.Denoising_Estimation_resta = false;
                        app.CNNModelLabel.Visible = "off";
                        app.CNNModel1DropDown_2.Visible = "off";
                        app.PhaseCompensation.Visible = "off";
                        app.Pam_sim.Actual_color = app.Pam_sim.colors(4); 

                    case "Perfect"
                        app.Pam_sim.CNNEstimation = false;
                        app.Pam_sim.Autoencoder_Estimation = false;
                        app.Pam_sim.EstimacionPractica = false;
                        app.Pam_sim.Denoising_Estimation = false;
                        
                        app.Pam_sim.Denoising_Estimation_resta = false;
                        app.CNNModelLabel.Visible = "off";
                        app.CNNModel1DropDown_2.Visible = "off";
                        app.PhaseCompensation.Visible = "off";

                        app.Pam_sim.Actual_color = app.Pam_sim.colors(5); 
                    case "Lineal"
                        app.Pam_sim.CNNEstimation = false;
                        app.Pam_sim.Autoencoder_Estimation = false;
                        app.Pam_sim.EstimacionPractica = false;
                        app.Pam_sim.Denoising_Estimation = false;
                        app.Pam_sim.Denoising_Estimation_resta = false;
                        app.CNNModelLabel.Visible = "off";
                        app.CNNModel1DropDown_2.Visible = "off";
                        app.PhaseCompensation.Visible = "off";

                        app.Pam_sim.Actual_color = app.Pam_sim.colors(6); 

                end

                value = app.ModulationDropDown_2.Value;
                switch value
                    case "QPSK"
                        app.Pam_sim.Actual_line = app.Pam_sim.lines(4);
                    case "16QAM"
                        app.Pam_sim.Actual_line = app.Pam_sim.lines(1);
                    case "64QAM"
                        app.Pam_sim.Actual_line = app.Pam_sim.lines(2);
                    case "256QAM"
                        app.Pam_sim.Actual_line = app.Pam_sim.lines(3);
                end


                app.Pam_sim.models = {'Lineal','Perfect'};

                if app.Pam_sim.CNNEstimation
                    app.Pam_sim.models = [app.Pam_sim.models 'CNN'];
                    app.Pam_sim.estimacionRNA_1 = load("CNN_Modelo"+app.Pam_sim.CNNmodel_1+".mat").estimacionRNA;
                    disp(["Modelo ",app.Pam_sim.CNNmodel_1," Cargado"])
                end
                if app.Pam_sim.Autoencoder_Estimation
                    app.Pam_sim.estimacionRNA_Autoencoder = load("Autoencoder.mat").estimacionRNA;
                    app.Pam_sim.models = [app.Pam_sim.models 'Autoencoder'];
                end
                if app.Pam_sim.EstimacionPractica
                    app.Pam_sim.models = [app.Pam_sim.models 'Practical'];
                end
                if app.Pam_sim.Denoising_Estimation
                    app.Pam_sim.estimacionRNA_Denoising= load("Denoising1.mat").estimacionRNA;
                    app.Pam_sim.models = [app.Pam_sim.models 'Denoising'];
                end
                if app.Pam_sim.Denoising_Estimation_resta
                    app.Pam_sim.estimacionRNA_Denoising_2= load("Denoising2.mat").estimacionRNA;
                    app.Pam_sim.models = [app.Pam_sim.models 'Denoising_2'];
                end

                %% Ciclo de Procesamiento (Simulacion transmision en el tiempo )

                app.Pam_sim.SNR_Recorridas =[];

                %Inicialización y configuración de la portadora, Modulacion y canaldB
                % Parametros : Tipo de modulacion, Perfil de canal, Velocidad Usuario
                app.Pam_sim = Simulation_Configuration(app.Pam_sim);% Parametros de la simulacion

                app.Pam_sim =Variables_initialization(app.Pam_sim,app.Pam_sim.SNR);
                app.Pam_sim.SNR_Recorridas = [app.Pam_sim.SNR_Recorridas app.Pam_sim.SNR];


                disp(['Slot ',num2str(app.Pam_sim.Indice_Ranura ),' Enviado'])
                app.Pam_sim.Indice_Ranura = app.Pam_sim.Indice_Ranura +1 ;
                %% ------------ Transmisor  ----------------------------
                tStart_trans = tic;
                app.Pam_sim = Transmitter(app.Pam_sim);
                app.Pam_sim.tEnd_trans = toc(tStart_trans);
                %% ------------ Receptor (AWGN - CANAL TDL) ---------------------------
                tStart_recep = tic;
                app.Pam_sim = Receiver(app.Pam_sim);
                app.Pam_sim.tEnd_recep = toc(tStart_recep);
                   %% ------------  Estimacion de Canal Lineal ---------------------------
                tStart_Lineal = tic;
                app.Pam_sim = Lineal_Estimation(app.Pam_sim);
                app.Pam_sim.tEnd_Lineal = toc(tStart_Lineal);
                %% ----------------- Estimacion de Canal Perfecta ---------------------
                tStart_Perfect = tic;
                app.Pam_sim = Perfect_Estimation(app.Pam_sim);
                app.Pam_sim.tEnd_Perfect = toc(tStart_Perfect);
                %% ----------------- Estimacion de Canal con CNN ---------------------
                if app.Pam_sim.CNNEstimation
                        tStart_CNN = tic;
                        app.Pam_sim = CNN_Estimation(app.Pam_sim,app.Pam_sim.estimacionRNA_1);
                        app.Pam_sim.tEnd_CNN = toc(tStart_CNN);
                        app.Pam_sim.CNN_pdschEq_sin = app.Pam_sim.CNN_pdschEq ;
                end
                %% ----------------- Estimacion de Canal con AUTOENCODER --------------
                if app.Pam_sim.Autoencoder_Estimation
                    tStart_Autoencoder= tic;
                    app.Pam_sim = Autoencoder_Estimation(app.Pam_sim,app.Pam_sim.estimacionRNA_Autoencoder);
                    app.Pam_sim.tEnd_Autoencoder = toc(tStart_Autoencoder);
                end
                %% ----------------- Estimacion de Canal con Red Denoising --------------
                if app.Pam_sim.Denoising_Estimation
                    tStart_Denoising= tic;
                    app.Pam_sim = Denoising_Estimation(app.Pam_sim,app.Pam_sim.estimacionRNA_Denoising);
                    app.Pam_sim.tEnd_Denoising= toc(tStart_Denoising);
                end
                %% ----------------- Estimacion de Canal con Red Denoising 2--------------
                if app.Pam_sim.Denoising_Estimation_resta
                    tStart_Denoising_2= tic;
                    app.Pam_sim = Denoising_Estimation_2(app.Pam_sim,app.Pam_sim.estimacionRNA_Denoising_2);
                    app.Pam_sim.tEnd_Denoising_2= toc(tStart_Denoising_2);
                end

                %% ----------------- Estimacion de Canal Practica ---------------------
                if app.Pam_sim.EstimacionPractica
                    tStart_Practical = tic;
                    app.Pam_sim = Practical_Estimation(app.Pam_sim);
                    app.Pam_sim.tEnd_Practical = toc(tStart_Practical);
                end
                %% ----------------- Compensacion de fase de los Simbolos ----------------
                if app.Pam_sim.Compensacion_Fase
                    %app.Pam_sim.Model_Phasecomp = app.phase_comp_model.Value;
                    tStart_Compensation = tic;
                    app.Pam_sim  = Phase_Compensantion(app.Pam_sim,app.Pam_sim.Model_Phasecomp);
                    app.Pam_sim.tEnd_Compensation = toc(tStart_Compensation);
                end
                
                app.Pam_sim = Metricas_Slot(app.Pam_sim);
                last = app.Pam_sim.(app.EstimationTypeDropDown.Value+"_estChannelGrid");
                app.Pam_sim.Grilla_Slots = [app.Pam_sim.Grilla_Slots last];
                
                EVM_Graph1 = app.Pam_sim.(app.EstimationTypeDropDown.Value+"_EVM");
                Time_Graph1 = app.Pam_sim.(app.EstimationTypeDropDown.Value+"_Time");
                Time_perfect = app.Pam_sim.Perfect_Time;

                %Time_Graph1 = Parameters.(app.Pam_sim.Grap1_type +"_Time");
                intervalo =  app.DatatoplotDropDown.Value;
                [fil,col]=size(app.Pam_sim.Grilla_Slots);
                slot_number = 10;
                app.Graph1_2.XAxisLocation = "top";

                app.Pam_sim.EVM  = [app.Pam_sim.EVM EVM_Graph1];
                app.Pam_sim.slot = [app.Pam_sim.slot app.Pam_sim.Indice_Ranura];
                
                app.Pam_sim.TIME  = [app.Pam_sim.TIME Time_Graph1];
                app.Pam_sim.TIME_PERFECT = [app.Pam_sim.TIME_PERFECT app.Pam_sim.Perfect_Time];
                % if app.Pam_sim.Compensacion_Fase
                %     app.Pam_sim.TIME  = [app.Pam_sim.TIME (Time_Graph1+app.Pam_sim.tEnd_Compensation)];
                %     app.Pam_sim.TIME_PERFECT = [app.Pam_sim.TIME_PERFECT (app.Pam_sim.Perfect_Time+app.Pam_sim.tEnd_Compensation)];
                %     disp(app.Pam_sim.TIME_PERFECT)
                %     disp(app.Pam_sim.TIME)
                % else
                %     app.Pam_sim.TIME  = [app.Pam_sim.TIME Time_Graph1];
                %     app.Pam_sim.TIME_PERFECT = [app.Pam_sim.TIME_PERFECT app.Pam_sim.Perfect_Time];
                %     disp(app.Pam_sim.TIME_PERFECT)
                %     disp(app.Pam_sim.TIME)
                % end

                switch intervalo
                    case "Last 10"
                        app.Graph1_2.XLimMode = "auto";
                        app.Graph1_2.YLimMode = "auto";

                        app.Graph1_3.XLimMode = "auto";
                        app.Graph1_3.YLimMode = "auto";

                        app.Graph1_4.XLimMode = "auto";
                        app.Graph1_4.YLimMode = "auto";
                        
                        if col>slot_number*14
                            last10 = app.Pam_sim.Grilla_Slots(1:fil,(col-slot_number*(app.Pam_sim.portadora.SymbolsPerSlot)+1):col);
                            imagesc(app.Graph1_2,abs(last10));
                            plot(app.Graph1_3,app.Pam_sim.slot(end-9:end),app.Pam_sim.EVM(end-9:end) ,'-o','color', app.Pam_sim.Actual_color,'LineStyle',app.Pam_sim.Actual_line);
                            plot(app.Graph1_4,app.Pam_sim.slot(end-9:end),app.Pam_sim.TIME(end-9:end)./app.Pam_sim.TIME_PERFECT(end-9:end)  ,'-o','color', app.Pam_sim.Actual_color,'LineStyle',app.Pam_sim.Actual_line);

                        else
                            imagesc(app.Graph1_2,abs(app.Pam_sim.Grilla_Slots));
                            plot(app.Graph1_3,app.Pam_sim.slot,app.Pam_sim.EVM ,'-o','color', app.Pam_sim.Actual_color,'LineStyle',app.Pam_sim.Actual_line);
                            plot(app.Graph1_4,app.Pam_sim.slot,app.Pam_sim.TIME./app.Pam_sim.TIME_PERFECT  ,'-o','color', app.Pam_sim.Actual_color,'LineStyle',app.Pam_sim.Actual_line);

                        end
                        

                    case "Last One"
                        app.Graph1_2.XLimMode = "auto";
                        app.Graph1_2.YLimMode = "auto";

                        app.Graph1_3.XLimMode = "auto";
                        app.Graph1_3.YLimMode = "auto";

                        app.Graph1_4.XLimMode = "auto";
                        app.Graph1_4.YLimMode = "auto";

                        plot(app.Graph1_3,app.Pam_sim.slot,app.Pam_sim.(app.EstimationTypeDropDown.Value+"_EVM") ,'-o','color', app.Pam_sim.Actual_color,'LineStyle',app.Pam_sim.Actual_line);
                        plot(app.Graph1_4,app.Pam_sim.slot,app.Pam_sim.(app.EstimationTypeDropDown.Value+"_Time")./app.Pam_sim.Perfect_Time ,'-o','color', app.Pam_sim.Actual_color,'LineStyle',app.Pam_sim.Actual_line);

                        imagesc(app.Graph1_2,abs(last));
                    case "All"
                        app.Graph1_2.XLimMode = "auto";
                        app.Graph1_2.YLimMode = "auto";

                        app.Graph1_3.XLimMode = "auto";
                        app.Graph1_3.YLimMode = "auto";

                        app.Graph1_4.XLimMode = "auto";
                        app.Graph1_4.YLimMode = "auto";

                        plot(app.Graph1_3,app.Pam_sim.slot,app.Pam_sim.EVM ,'-o','color', app.Pam_sim.Actual_color,'LineStyle',app.Pam_sim.Actual_line);
                        plot(app.Graph1_4,app.Pam_sim.slot,app.Pam_sim.TIME./app.Pam_sim.TIME_PERFECT  ,'-o','color', app.Pam_sim.Actual_color,'LineStyle',app.Pam_sim.Actual_line);

                        imagesc(app.Graph1_2,abs(app.Pam_sim.Grilla_Slots));
                end

            end
            uialert(app.DLS5GUIFigure,[' Simulation Stoped '],"Invalid File","Icon","error");
            app = Init_Parameters(app);
            catch error
                uialert(app.DLS5GUIFigure,['  There was an error! The message was:   ',error.message, ' the identifier was: ',error.identifier,],"Invalid File","Icon","error");
            end

            %% ------------------------------- END SIMULATION --------------------------------------

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Pam_sim.Stop_RealTime = true;
            disp("Off");
        end

        % Value changed function: RangeSliderSNR
        function changedSNRRange(app, event)

        end

        % Value changing function: RangeSliderSNR
        function changingSNRRange(app, event)
            value = event.Value;
            min = round(value(1));
            max = round(value(2));
            app.RangeSNRLabel.Text = "Range: "+ int2str(min) + " - " + int2str(max);
            app.Pam_sim.SNR_Init = min;
            app.Pam_sim.SNR_max = max;
        end

        % Callback function
        function RangeSlider_VelValueChanging(app, event)
            value = event.Value;
            min = round(value(1));
            max = round(value(2));
            app.RangeVelLabel.Text = "Range: "+ int2str(min) + " - " + int2str(max);
            app.Pam_sim.Vel_init = min;
            app.Pam_sim.Vel_end = max;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
                
            app.Pam_sim.Simple_sim = true;
            if app.Pam_sim.Transmision_IMG
                app.TabGroup.SelectedTab = app.External_plots;
                app.External_plots.Title = "Received Images";
                 drawnow
                delete(app.External_plots.Children);
            end
            %Se recuperan los checkbox del panel de modelo
            app = Complet_Simulation(app);
            app.Pam_sim.Simple_sim = false;
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            
            app.TabGroup.SelectedTab = app.TimeSimulationTab;
            delete(app.External_plots.Children)
            app.External_plots.Title = "Constellations"
            drawnow
            checkboxes = findall(app.PanelModels,'type','uicheckbox');
            select_CheckBox = {};
            def = {'Perfect', 'Lineal'};
            for i=1:numel(checkboxes)
                if get(checkboxes(i), "Value") == 1
                      if ~strcmp(checkboxes(i).Text,'Phase Com')
                            select_CheckBox{end+1} = checkboxes(i).Text;
                      end
                end
            end
            app.Grafica1DropDown.Items = string([select_CheckBox, def]);
            app.Grafica2DropDown.Items = string([select_CheckBox, def]);
            app.Grafica3DropDown.Items = string([select_CheckBox, def]);

            app.Grafica1DropDown.Enable = "on";
            app.Grafica2DropDown.Enable = 'on';
            app.Grafica3DropDown.Enable = 'on';
            
            app.Pam_sim.Time_sim = true;
            app.Pam_sim.Simple_sim = false;
            if app.Pam_sim.Transmision_IMG
                app.TabGroup.SelectedTab = app.External_plots;
                app.External_plots.Title = "Received Images";
                delete(app.External_plots.Children);
            end
            app = Complet_Simulation(app);
            app.Pam_sim.Time_sim = false;
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
             % Display uigetfile dialog
            filterspec = {'*.jpeg;*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file, path] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                app.Pam_sim.Path_IMG = [path file];
                uialert(app.DLS5GUIFigure,"Image Uploaded","Imge","Icon","success");
            end
        end

        % Clicked callback: EstimationTypeDropDown
        function EstimationTypeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            disp(item)
        end

        % Clicked callback: DatatoplotDropDown
        function DatatoplotDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: DatatoplotDropDown
        function DatatoplotDropDownValueChanged(app, event)
            value = app.DatatoplotDropDown.Value;
            
        end

        % Value changed function: ModulationDropDown_2
        function ModulationDropDown_2ValueChanged(app, event)
            value = app.ModulationDropDown_2.Value;
            
        end

        % Callback function
        function GraphUnique_2ButtonPushed(app, event)
            %             app.Pam_sim.marks = ["o","pentagram","square","diamond","^","+","hexagram",".","x","_","|","square","diamond","^","v","<",">","*"];
            % app.Pam_sim.lines = ["-","--",":","-."];
            % app.Pam_sim.colors = ["r","b","g","m","c","y","k"];
            colors = ["#FC572A","#000000","#00EEBB","#0036EE","#EE00C3","#774747","#7ECD19","#AA731B"];

            Line_Width = 1.3;
            Paths = [];
            label = [];
            Styles = [];
            colors_plot = [];
            %% ################## GRAPH PARAMETERS ########################

            app.Pam_sim.gf_Metric1 = app.GraphTypeDropDown_2.Value;
            app.Pam_sim.gf_Modulation = app.SelectFileDropDown_2.Value;
            app.Pam_sim.gf_Channel = app.DropDown_4.Value;
            app.Pam_sim.gf_Metric2 = vertcat(app.GraphTypeDropDown_3.Value);

            app.Pam_sim.gf_models = [app.ListBox.Value ""];
            app.Pam_sim.gf_models(end)=[];
            
            app.Pam_sim.gf_Files_Path = "Channel_";
            app.Pam_sim.gf_vel = string(5);
            %% ################## LOAD FILES       ########################
            
            switch app.Pam_sim.gf_Channel
                case "TDL-E"
                    if app.Pam_sim.gf_Modulation == "QPSK"
                        app.Pam_sim.gf_Files_Path = app.Pam_sim.gf_Files_Path+"TDL-E_Mod_QPSK_Vel_"+app.Pam_sim.gf_vel+".mat";
                    elseif app.Pam_sim.gf_Modulation == "64QAM"
                        app.Pam_sim.gf_Files_Path = app.Pam_sim.gf_Files_Path+"TDL-E_Mod_64QAM_Vel_"+app.Pam_sim.gf_vel+".mat";
                    else
                        app.Pam_sim.gf_Files_Path = app.Pam_sim.gf_Files_Path+"TDL-E_Mod_256QAM_Vel_"+app.Pam_sim.gf_vel+".mat";
                    end
                case "TDL-B"
                    if app.Pam_sim.gf_Modulation == "QPSK"
                        app.Pam_sim.gf_Files_Path = app.Pam_sim.gf_Files_Path+"TDL-B_Mod_QPSK_Vel_"+app.Pam_sim.gf_vel+".mat";
                    elseif app.Pam_sim.gf_Modulation == "64QAM"
                        app.Pam_sim.gf_Files_Path = app.Pam_sim.gf_Files_Path+"TDL-B_Mod_64QAM_Vel_"+app.Pam_sim.gf_vel+".mat";
                    else
                        app.Pam_sim.gf_Files_Path = app.Pam_sim.gf_Files_Path+"TDL-B_Mod_256QAM_Vel_"+app.Pam_sim.gf_vel+".mat";
                    end
            end

            % For any TDL Channel
            % Change path in function of the O.S
            if (isunix) % working in UNIX env.
                DATA_PATH_NAME_IMG = "Outputs/Images/";
                DATA_PATH_NAME = "Outputs/"+app.Pam_sim.gf_Channel+"/";
            else % working in Windows env.
                DATA_PATH_NAME_IMG = "Outputs\Images\";
                DATA_PATH_NAME = "Outputs\"+app.Pam_sim.gf_Channel+"\";
            end
            addpath(DATA_PATH_NAME)
            disp(DATA_PATH_NAME)
            %Read All filles with extension .mat

            Files = dir(DATA_PATH_NAME+"*.mat");
            numfiles = length(Files);
            mydata = cell(1, numfiles);
            for i = 1:numfiles
                Paths = [Paths string(Files(i).name)];
            end
            disp("Generating Plots for "+app.Pam_sim.gf_Files_Path)

            try
                app.Pam_sim.datos = load(app.Pam_sim.gf_Files_Path).Parameters;
                uialert(app.DLS5GUIFigure,[app.Pam_sim.gf_Files_Path ," Uploaded"],"Matrix","Icon","success");
            catch error
                uialert(app.DLS5GUIFigure,['  There was an error! The message was:   ',error.message, ' the identifier was: ',error.identifier,],"Invalid File","Icon","error");
            end
            
            
            hold on
            
            for k = 1:length(app.Pam_sim.gf_models)
                plot(x,y,Styles(a), LineWidth=Line_Width)
            end


            switch app.Pam_sim.gf_Metric1
                case "EVM"
                   
                    xlabel('SNR (dB)');ylabel("EVM(%)");
                    set(gca,'xminorgrid','on','yminorgrid','on','XMinorTick','on','YMinorTick','on');
                    xticks([0:1:25]);
                case "BER"
                    hold on
                    yscale log;
                    xlabel('SNR (dB)');ylabel(Metric);
                    set(gca,'xminorgrid','on','yminorgrid','on','XMinorTick','on','YMinorTick','on');
                    xticks([0:1:25]);
                case "MSE"
                    xlabel('SNR (dB)');ylabel(Metric);
                    set(gca,'xminorgrid','on','yminorgrid','on','XMinorTick','on','YMinorTick','on');
                    xticks([0:1:25]);
                case "Time"
                    hold on
                    title('Tiempo de Estimación');xlabel('SNR (dB)'); ylabel('Estimación (ms)');
                    set(gca,'xminorgrid','on','yminorgrid','on','XMinorTick','on','YMinorTick','on');
                    xticks([0:1:25]);
            end

            switch app.Pam_sim.gf_Metric2
                case "EVM"
                    xlabel('SNR (dB)');ylabel("EVM(%)");
                    set(gca,'xminorgrid','on','yminorgrid','on','XMinorTick','on','YMinorTick','on');
                    xticks([0:1:25]);
                case "BER"
                    hold on
                    yscale log;
                    xlabel('SNR (dB)');ylabel(Metric);
                    set(gca,'xminorgrid','on','yminorgrid','on','XMinorTick','on','YMinorTick','on');
                    xticks([0:1:25]);
                case "MSE"
                    xlabel('SNR (dB)');ylabel(Metric);
                    set(gca,'xminorgrid','on','yminorgrid','on','XMinorTick','on','YMinorTick','on');
                    xticks([0:1:25]);
                case "Time"
                    hold on
                    title('Tiempo de Estimación');xlabel('SNR (dB)'); ylabel('Estimación (ms)');
                    set(gca,'xminorgrid','on','yminorgrid','on','XMinorTick','on','YMinorTick','on');
                    xticks([0:1:25]);
            end


            % for i = interval
            %     %load all data in a cell 
            %     disp("Reading "+Paths(i) )
            %     if Paths(i) == app.Pam_sim.gf_Files_Path
            %         disp("Matriz cargada")
            %         app.("Simulation_"+string(i)) = load(Paths(i)).Parameters;
            %         label = [label (app.Pam_sim.gf_Files_Path+" "+app.Pam_sim.gf_Modulation)];
            %     end
            % 
            % end
            % 
            % 


            % Read Complet Path with Velocity
          

        end

        % Callback function
        function GraphTypeDropDown_3ValueChanged(app, event)
            app.Pam_sim.gf_Metric2 = app.GraphTypeDropDown_3.Value;
            
        end

        % Callback function
        function GraphTypeDropDown_2ValueChanged(app, event)
            app.Pam_sim.gf_Metric1 = app.GraphTypeDropDown_2.Value;
            
        end

        % Callback function
        function SelectFileDropDown_2ValueChanged(app, event)
            app.Pam_sim.gf_Modulation = app.SelectFileDropDown_2.Value;
            
        end

        % Callback function
        function DropDown_4ValueChanged(app, event)
            app.Pam_sim.gf_Channel = app.DropDown_4.Value;
             
        end

        % Callback function
        function Grafica1DropDownValueChanged2(app, event)
            app.Pam_sim.Grap1_type = app.Grafica1DropDown.Value;
            app.Model1Label.Text = app.Pam_sim.Grap1_type;
            
            switch app.Pam_sim.Grap1_type
                case "CNN"
                    app.Graph1.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph1.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph1.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph1.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph1.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph1.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                     app.Graph1.Title.String = "Interpolation Model"  ;
            end


        end

        % Callback function
        function Grafica2DropDownValueChanged2(app, event)
                      app.Pam_sim.Grap2_type = app.Grafica2DropDown.Value;
            app.Model2Label.Text = app.Pam_sim.Grap2_type;
            
            switch app.Pam_sim.Grap2_type
                case "CNN"
                    app.Graph2.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph2.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph2.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph2.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph2.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph2.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                    app.Graph2.Title.String = "Interpolation Model"  ;
            end
            
        end

        % Callback function
        function Grafica3DropDownValueChanged2(app, event)
                     app.Pam_sim.Grap3_type = app.Grafica3DropDown.Value;
            app.Model3Label.Text = app.Pam_sim.Grap3_type;
            
            switch app.Pam_sim.Grap3_type
                case "CNN"
                    app.Graph3.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph3.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph3.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph3.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph3.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph3.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                    app.Graph3.Title.String = "Interpolation Model"  ;
            end
            
        end

        % Callback function
        function ShowTableButtonPushed2(app, event)
                          app.Model1_TimeLabel.Visible = "on";
                app.Model2_TimeLabel.Visible = "on";
                app.Model3_TimeLabel.Visible = "on";

                app.Model1_EVMLabel.Visible = "on";
                app.Model2_EVMLabel.Visible = "on";
                app.Model3_EVMLabel.Visible = "on";

                app.Model1_MSELabel.Visible = "on";
                app.Model2_MSELabel.Visible = "on";
                app.Model3_MSELabel.Visible = "on";

                app.Model1Label.Visible = "on";
                app.Model2Label.Visible = "on";
                app.Model3Label.Visible= "on";

                app.TimemsLabel.Visible = "on";
                app.EVMLabel.Visible = "on";
                app.MSELabel.Visible = "on";
        end

        % Callback function
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            %app.PauseButton.Visible = "on";
            %app.StopButton.Visible = "on";
            if value
                app.StopButton.Enable = 'off';
                app.PauseButton.Text = 'Resume';
            else
                app.StopButton.Enable = 'on';
                app.PauseButton.Text = 'Pause';
            end

            
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.Pam_sim.stop = app.PauseButton.Value;
            drawnow
            if app.Pam_sim.stop
                stop();
                disp('Unknown flag value.')
            end
        end

        % Value changed function: Grafica1DropDown
        function Grafica1DropDownValueChanged3(app, event)
            app.Pam_sim.Grap1_type = app.Grafica1DropDown.Value;
            app.Model1Label.Text = app.Pam_sim.Grap1_type;
            
            switch app.Pam_sim.Grap1_type
                case "CNN"
                    app.Graph1.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph1.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph1.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph1.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph1.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph1.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                     app.Graph1.Title.String = "Interpolation Model"  ;
            end


        end

        % Value changed function: Grafica2DropDown
        function Grafica2DropDownValueChanged3(app, event)

                        app.Pam_sim.Grap2_type = app.Grafica2DropDown.Value;
            app.Model2Label.Text = app.Pam_sim.Grap2_type;
            
            switch app.Pam_sim.Grap2_type
                case "CNN"
                    app.Graph2.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph2.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph2.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph2.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph2.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph2.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                    app.Graph2.Title.String = "Interpolation Model"  ;
            end
        end

        % Value changed function: Grafica3DropDown
        function Grafica3DropDownValueChanged3(app, event)
            app.Pam_sim.Grap3_type = app.Grafica3DropDown.Value;
            app.Model3Label.Text = app.Pam_sim.Grap3_type;
            
            switch app.Pam_sim.Grap3_type
                case "CNN"
                    app.Graph3.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph3.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph3.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph3.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph3.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph3.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                    app.Graph3.Title.String = "Interpolation Model"  ;
            end
            
        end

        % Button pushed function: ShowTableButton
        function ShowTableButtonPushed3(app, event)
            
                app.Model1_TimeLabel.Visible = "on";
                app.Model2_TimeLabel.Visible = "on";
                app.Model3_TimeLabel.Visible = "on";

                app.Model1_EVMLabel.Visible = "on";
                app.Model2_EVMLabel.Visible = "on";
                app.Model3_EVMLabel.Visible = "on";

                app.Model1_MSELabel.Visible = "on";
                app.Model2_MSELabel.Visible = "on";
                app.Model3_MSELabel.Visible = "on";

                app.Model1Label.Visible = "on";
                app.Model2Label.Visible = "on";
                app.Model3Label.Visible= "on";

                app.TimemsLabel.Visible = "on";
                app.EVMLabel.Visible = "on";
                app.MSELabel.Visible = "on";

        end

        % Value changed function: NormalizedTimeCheckBox
        function NormalizedTimeCheckBoxValueChanged(app, event)
            app.Pam_sim.Normalized_Time= app.NormalizedTimeCheckBox.Value;
        end

        % Value changed function: phase_comp_model
        function phase_comp_modelValueChanged(app, event)
            app.Pam_sim.Model_Phasecomp = app.phase_comp_model.Value;
            
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            if ~isempty(findall(groot, 'Type', 'uifigure', 'Name', 'About DLS5G'))
                about_us;
            end

about_us;
        end

        % Value changed function: TypeVelocityDropDown
        function TypeVelocityDropDownValueChanged2(app, event)
                     value = app.TypeVelocityDropDown.Value;
            switch value
                case "Static"
                    app.Pam_sim.Vel_sim_Estatic = true;
                    app.RangeSlider_Vel.Value = [0,120];
                    app.ValueLabelVel.Visible = "on";
                    app.EditField_vel_init.Visible = "on";
                    app.RangeSlider_Vel.Visible = "off";
                    app.RangeVelLabel.Visible = "off";
                    app.RangeVelLabel.Text = "Range: "+int2str(0) + " - " + int2str(120);
                case "Interval"
                    app.Pam_sim.Vel_sim_Estatic = false;
                    app.ValueLabelVel.Visible = "off";
                    app.EditField_vel_init.Visible = "off";
                    app.RangeSlider_Vel.Visible = "on";
                    app.RangeSlider_Vel.Enable = 'on';
                    app.RangeVelLabel.Visible = "on";
                otherwise             
            end   
        end

        % Value changing function: RangeSlider_Vel
        function RangeSlider_VelValueChanging2(app, event)
            value = event.Value;
            min = round(value(1));
            max = round(value(2));
            app.RangeVelLabel.Text = "Range: "+ int2str(min) + " - " + int2str(max);
            app.Pam_sim.Vel_init = min;
            app.Pam_sim.Vel_end = max;
        end

        % Callback function
        function ConstellationsButtonPushed(app, event)
            % Plot the actual constellations 


        end

        % Callback function
        function PlotConstellationsCheckBoxValueChanged(app, event)
            
            app.Pam_sim.Constellations_list = app.Grafica1DropDown.Items;
            app.Pam_sim.Constellations_plot = app.PlotConstellationsCheckBox.Value;
            
            % if isempty(findall(groot, 'Type', 'figure', 'Name', 'Constellations plot'))
            %     disp("Plot creado")
            %     app.Pam_sim.const_plot = figure('Name', 'Constellations plot');
            %     app.PlotConstellationsCheckBox.Enable = "off";
            % else
            %     disp("Plot existe")
            % end

        end

        % Button pushed function: Plot_Constellations
        function Plot_ConstellationsButtonPushed(app, event)
            app.Pam_sim.Constellations_list = app.Grafica1DropDown.Items;
            app.Pam_sim.Constellations_plot = true;         

            app.TabGroup.SelectedTab = app.External_plots;
            delete(app.External_plots.Children)
            app.External_plots.Title = "Constellations ";

        end

        % Value changed function: SymbolsButton
        function SymbolsButtonValueChanged(app, event)
            try
                cla(app.Symbol)
                app.Symbol.XLimMode = "auto";
                app.Symbol.YLimMode = "auto";


                app.Pam_sim.Symbol_plot = app.EditFieldSymbol.Value;
                models_dropdown = [string(app.Pam_sim.Grap1_type) string(app.Pam_sim.Grap2_type) string(app.Pam_sim.Grap3_type)];
                i=0;
                drawnow
                hold(app.Symbol,"on");
                label = models_dropdown;
                for models = models_dropdown

                    i=i+1;
                    sim = abs(app.Pam_sim.("Time_Grid_"+models));
                    rx1 = 10*log10(sim(:,app.Pam_sim.Symbol_plot,:));
                    plot(app.Symbol,rx1,Color=app.Pam_sim.colors_plot(i),LineWidth =app.Pam_sim.LineWidth);
       
                    if models=="Perfect"
                        label(i) = "Ideal";
                    end
                end
                legend(app.Symbol,label, 'Color','w')
            catch error
                message = error.message;
                id = error.identifier;
                if (strcmp(id,'MATLAB:badsubscript'))
                    uialert(app.DLS5GUIFigure,['This symbols is not generated yet'],"Invalid File","Icon","info");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DLS5GUIFigure = uifigure('Visible', 'off');
            app.DLS5GUIFigure.Name = 'DLS5G';
            app.DLS5GUIFigure.Icon = 'logo.jpg';
            app.DLS5GUIFigure.CloseRequestFcn = createCallbackFcn(app, @DLS5GUIFigureCloseRequest, true);
            app.GridLayout2 = uigridlayout(app.DLS5GUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Tooltip = {''};
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout = uigridlayout(app.ParametersTab);
            app.PanelTitle = uipanel(app.GridLayout);
            app.PanelTitle.Title = 'Initial Parameters';
            app.PanelChannelConfig = uipanel(app.GridLayout);
            app.PanelChannelConfig.Tooltip = {'In this section you could select one of the TDL profiles and the modulation to be used in the data transmission through PDSCH '};
            app.PanelChannelConfig.Title = 'Channel Configuration';
            app.GridChannel = uigridlayout(app.PanelChannelConfig);
            app.ChannelProfileDropDownLabel = uilabel(app.GridChannel);
            app.ChannelProfileDropDownLabel.Text = 'Channel Profile';
            app.ChannelProfileDropDown = uidropdown(app.GridChannel);
            app.ChannelProfileDropDown.Items = {'TDL-A', 'TDL-B', 'TDL-C', 'TDL-D', 'TDL-E'};
            app.ChannelProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelProfileDropDownValueChanged, true);
            app.ChannelProfileDropDown.Value = 'TDL-A';
            app.ModulationDropDownLabel = uilabel(app.GridChannel);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.GridChannel);
            app.ModulationDropDown.Items = {'QPSK', '16QAM', '64QAM', '256QAM'};
            app.ModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationDropDownValueChanged, true);
            app.ModulationDropDown.Value = 'QPSK';
            app.PanelModels = uipanel(app.GridLayout);
            app.PanelModels.Tooltip = {'Click on the CNN you want to use in the channel estimation. With CNN 1 and CNN 2, you can select one of eight models generated, to review the specs of each one, read the documentation.'};
            app.PanelModels.Title = 'ANN Models';
            app.GridLayout4 = uigridlayout(app.PanelModels);
            app.CNNModel1CheckBox = uicheckbox(app.GridLayout4);
            app.CNNModel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @CNNModel1CheckBoxValueChanged, true);
            app.CNNModel1CheckBox.Text = 'CNN';
            app.CNNModel1CheckBox.Value = true;
            app.CNNModel1DropDown = uidropdown(app.GridLayout4);
            app.CNNModel1DropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.CNNModel1DropDown.ValueChangedFcn = createCallbackFcn(app, @CNNModel1DropDownValueChanged, true);
            app.CNNModel1DropDown.Value = '1';
            app.CNNModel2CheckBox = uicheckbox(app.GridLayout4);
            app.CNNModel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CNNModel2CheckBoxValueChanged, true);
            app.CNNModel2CheckBox.Text = 'CNN_2';
            app.CNNModel2DropDown = uidropdown(app.GridLayout4);
            app.CNNModel2DropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.CNNModel2DropDown.ValueChangedFcn = createCallbackFcn(app, @CNNModel2DropDownValueChanged, true);
            app.CNNModel2DropDown.Visible = 'off';
            app.CNNModel2DropDown.Value = '1';
            app.AutoencoderCheckBox = uicheckbox(app.GridLayout4);
            app.AutoencoderCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoencoderCheckBoxValueChanged, true);
            app.AutoencoderCheckBox.Text = 'Autoencoder';
            app.PracticalCheckBox = uicheckbox(app.GridLayout4);
            app.PracticalCheckBox.ValueChangedFcn = createCallbackFcn(app, @PracticalCheckBoxValueChanged, true);
            app.PracticalCheckBox.Text = 'Practical';
            app.PracticalCheckBox.Value = true;
            app.Denoising1CheckBox = uicheckbox(app.GridLayout4);
            app.Denoising1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Denoising1CheckBoxValueChanged, true);
            app.Denoising1CheckBox.Text = 'Denoising';
            app.Denoising2CheckBox = uicheckbox(app.GridLayout4);
            app.Denoising2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Denoising2CheckBoxValueChanged, true);
            app.Denoising2CheckBox.Text = 'Denoising_2';
            app.CNNPhaseCompensationCheckBox = uicheckbox(app.GridLayout4);
            app.CNNPhaseCompensationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CNNPhaseCompensationCheckBoxValueChanged, true);
            app.CNNPhaseCompensationCheckBox.Text = 'Phase Com';
            app.phase_comp_model = uidropdown(app.GridLayout4);
            app.phase_comp_model.Items = {'CNN'};
            app.phase_comp_model.ValueChangedFcn = createCallbackFcn(app, @phase_comp_modelValueChanged, true);
            app.phase_comp_model.Visible = 'off';
            app.phase_comp_model.Value = 'CNN';
            app.PanelSNR = uipanel(app.GridLayout);
            app.PanelSNR.Tooltip = {'Configure the type of SNR you want to have in the channel configuration. There are two main options: static, where you have the possibility to edit the value and the other type is with a defined range (min-max), which is entered with a range slider.'};
            app.PanelSNR.Title = 'SNR (dB)';
            app.GridSNR = uigridlayout(app.PanelSNR);
            app.RangeSliderSNR = uislider(app.GridSNR, 'range');
            app.RangeSliderSNR.Limits = [0 30];
            app.RangeSliderSNR.ValueChangedFcn = createCallbackFcn(app, @changedSNRRange, true);
            app.RangeSliderSNR.ValueChangingFcn = createCallbackFcn(app, @changingSNRRange, true);
            app.RangeSliderSNR.MinorTicks = [];
            app.RangeSliderSNR.Enable = 'off';
            app.RangeSliderSNR.Visible = 'off';
            app.RangeSliderSNR.Value = [0 30];
            app.ValueLabelSNR = uilabel(app.GridSNR);
            app.ValueLabelSNR.Text = 'Value';
            app.EditField_SNRValue = uieditfield(app.GridSNR, 'numeric');
            app.EditField_SNRValue.Limits = [0 30];
            app.EditField_SNRValue.RoundFractionalValues = 'on';
            app.EditField_SNRValue.ValueChangedFcn = createCallbackFcn(app, @EditField_SNRValueValueChanged, true);
            app.EditField_SNRValue.Value = 1;
            app.TypeDropDownLabel = uilabel(app.GridSNR);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeSNRDropDown = uidropdown(app.GridSNR);
            app.TypeSNRDropDown.Items = {'Static', 'Interval'};
            app.TypeSNRDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TypeSNRDropDownOpening3, true);
            app.TypeSNRDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeSNRDropDownValueChanged, true);
            app.TypeSNRDropDown.Value = 'Static';
            app.RangeSNRLabel = uilabel(app.GridSNR);
            app.RangeSNRLabel.Visible = 'off';
            app.RangeSNRLabel.Text = '';
            app.PanelSpeed = uipanel(app.GridLayout);
            app.PanelSpeed.Tooltip = {'Configure the speed of the UE. Same as the SNR panel, you can select two types of speed: static with a single value between 0 and 120 kmph and with a range between those values.'};
            app.PanelSpeed.Title = 'Speed (km/h)';
            app.GridLayout3 = uigridlayout(app.PanelSpeed);
            app.TypeDropDownLabel_2 = uilabel(app.GridLayout3);
            app.TypeDropDownLabel_2.Text = 'Type';
            app.TypeVelocityDropDown = uidropdown(app.GridLayout3);
            app.TypeVelocityDropDown.Items = {'Static', 'Interval'};
            app.TypeVelocityDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeVelocityDropDownValueChanged2, true);
            app.TypeVelocityDropDown.Value = 'Static';
            app.ValueLabelVel = uilabel(app.GridLayout3);
            app.ValueLabelVel.Text = 'Value';
            app.RangeSlider_Vel = uislider(app.GridLayout3, 'range');
            app.RangeSlider_Vel.Limits = [0 120];
            app.RangeSlider_Vel.ValueChangingFcn = createCallbackFcn(app, @RangeSlider_VelValueChanging2, true);
            app.RangeSlider_Vel.Enable = 'off';
            app.RangeSlider_Vel.Visible = 'off';
            app.RangeSlider_Vel.Value = [0 120];
            app.EditField_vel_init = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_vel_init.Limits = [1 150];
            app.EditField_vel_init.RoundFractionalValues = 'on';
            app.EditField_vel_init.Value = 1;
            app.RangeVelLabel = uilabel(app.GridLayout3);
            app.RangeVelLabel.Visible = 'off';
            app.RangeVelLabel.Text = '';
            app.Actions = uipanel(app.GridLayout);
            app.Actions.Tooltip = {'Choose the type of data to be transmited through the previous configured channel. You could select 3 types of data: frames, bits and images (you must upload a file).'; ''; 'Export Parameters if you want to store the information generated.'; ''; 'Simple simultation: is a button used for making the channels estimations with the selected models.'; ''; 'Time simulation: This button is useful if you want to see how the models estimate the channel during a period of time (according to data type).'; ''; 'Load File: This button allows you to upload the image to be transmitted.'};
            app.Actions.Title = 'Data Transmission and Simulation';
            app.GridLayout5 = uigridlayout(app.Actions);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.DataDropDownLabel = uilabel(app.GridLayout6);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.GridLayout6);
            app.DataDropDown.Items = {'Frames', 'Bits', 'Image'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = 'Frames';
            app.FramesNumberEditField = uieditfield(app.GridLayout6, 'text');
            app.FramesNumberEditField.ValueChangedFcn = createCallbackFcn(app, @FramesNumberEditFieldValueChanged, true);
            app.FramesNumberEditField.Value = '1';
            app.FramesLabel = uilabel(app.GridLayout6);
            app.FramesLabel.Text = 'Frames';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.Image = uiimage(app.GridLayout7);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'play.png');
            app.Image2 = uiimage(app.GridLayout7);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'time_simul.png');
            app.Image3 = uiimage(app.GridLayout7);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.Enable = 'off';
            app.Image3.AltText = 'Load an image';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'upload.png');
            app.ExportParametersCheckBox = uicheckbox(app.GridLayout7);
            app.ExportParametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportParametersCheckBoxValueChanged, true);
            app.ExportParametersCheckBox.Text = {'Export '; 'Parameters'};
            app.ExportParametersCheckBox.Value = true;
            app.SimpleSimulationLabel = uilabel(app.GridLayout7);
            app.SimpleSimulationLabel.Text = {'Simple'; 'Simulation'};
            app.TimeSimulationLabel = uilabel(app.GridLayout7);
            app.TimeSimulationLabel.Text = {'Time'; 'Simulation'};
            app.LoadFileLabel = uilabel(app.GridLayout7);
            app.LoadFileLabel.Text = {'Load'; 'File'};
            app.Image4 = uiimage(app.GridLayout7);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'about.png');
            app.AbouttheAppLabel = uilabel(app.GridLayout7);
            app.AbouttheAppLabel.Text = {'About'; 'the App'};
            app.Metricas = uitab(app.TabGroup);
            app.Metricas.SizeChangedFcn = createCallbackFcn(app, @MetricasSizeChanged, true);
            app.Metricas.Title = 'Graphics';
            app.GridMetricas = uigridlayout(app.Metricas);
            app.GraphicsPanel = uipanel(app.GridMetricas);
            app.GraphicsPanel.Title = 'Graphics';
            app.GridDistribution = uigridlayout(app.GridMetricas);
            app.GridOptions = uigridlayout(app.GridDistribution);
            app.GraphTypeLabel = uilabel(app.GridOptions);
            app.GraphTypeLabel.Text = 'Graph Type ';
            app.GraphTypeDropDown = uidropdown(app.GridOptions);
            app.GraphTypeDropDown.Items = {'Current Simulation', 'Load File'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'Current Simulation';
            app.SelectFileLabel = uilabel(app.GridOptions);
            app.SelectFileLabel.Visible = 'off';
            app.SelectFileLabel.Text = 'Select File';
            app.SelectFileDropDown = uidropdown(app.GridOptions);
            app.SelectFileDropDown.Items = {''};
            app.SelectFileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFileDropDownValueChanged, true);
            app.SelectFileDropDown.Visible = 'off';
            app.SelectFileDropDown.Value = '';
            app.DropDown = uidropdown(app.GridOptions);
            app.DropDown.Items = {'Multiple', 'Unique'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged2, true);
            app.DropDown.Value = 'Multiple';
            app.StyleGraphLabel = uilabel(app.GridOptions);
            app.StyleGraphLabel.Text = 'Style Graph';
            app.DropDown_2 = uidropdown(app.GridOptions);
            app.DropDown_2.Items = {'BER vs SNR', 'EVM vs SNR', 'MSE vs SNR', 'Time vs SNR'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = 'BER vs SNR';
            app.GraphUnique = uibutton(app.GridOptions, 'push');
            app.GraphUnique.ButtonPushedFcn = createCallbackFcn(app, @GraphUniqueButtonPushed, true);
            app.GraphUnique.Icon = fullfile(pathToMLAPP, 'graphic.png');
            app.GraphUnique.Visible = 'off';
            app.GraphUnique.Text = 'Graph';
            app.ClearUnique = uibutton(app.GridOptions, 'push');
            app.ClearUnique.ButtonPushedFcn = createCallbackFcn(app, @ClearUniqueButtonPushed, true);
            app.ClearUnique.Icon = fullfile(pathToMLAPP, 'clean.png');
            app.ClearUnique.Visible = 'off';
            app.ClearUnique.Text = 'Clear';
            app.MultiplePlotPanel = uipanel(app.GridDistribution);
            app.GridMultiple = uigridlayout(app.MultiplePlotPanel);
            app.Unique = uiaxes(app.GridMultiple);
            title(app.Unique, 'Graph')
            xlabel(app.Unique, 'x')
            ylabel(app.Unique, 'Y')
            zlabel(app.Unique, 'Z')
            app.Unique.Visible = 'off';
            app.TIME = uiaxes(app.GridMultiple);
            title(app.TIME, 'Time(ms)')
            xlabel(app.TIME, 'X')
            ylabel(app.TIME, 'Estimation')
            zlabel(app.TIME, 'Z')
            app.MSE = uiaxes(app.GridMultiple);
            title(app.MSE, 'Mean Square Error')
            xlabel(app.MSE, 'SNR (dB)')
            ylabel(app.MSE, 'MSE')
            zlabel(app.MSE, 'Z')
            app.BER = uiaxes(app.GridMultiple);
            title(app.BER, 'Bit Error Rate')
            xlabel(app.BER, 'SNR (dB)')
            ylabel(app.BER, 'BER')
            zlabel(app.BER, 'Z')
            app.BER.YScale = 'log';
            app.EVM = uiaxes(app.GridMultiple);
            title(app.EVM, 'Error vector Machine')
            xlabel(app.EVM, 'SNR (dB)')
            ylabel(app.EVM, 'EVM (%)')
            zlabel(app.EVM, 'Z')
            app.GraphBER = uibutton(app.GridMultiple, 'push');
            app.GraphBER.ButtonPushedFcn = createCallbackFcn(app, @GraphBERPushed, true);
            app.GraphBER.Icon = fullfile(pathToMLAPP, 'graphic.png');
            app.GraphBER.Text = 'Graph';
            app.ClearBER = uibutton(app.GridMultiple, 'push');
            app.ClearBER.ButtonPushedFcn = createCallbackFcn(app, @ClearBERPushed, true);
            app.ClearBER.Icon = fullfile(pathToMLAPP, 'clean.png');
            app.ClearBER.Text = 'Clear';
            app.GraphEVM = uibutton(app.GridMultiple, 'push');
            app.GraphEVM.ButtonPushedFcn = createCallbackFcn(app, @GraphEVMPushed, true);
            app.GraphEVM.Icon = fullfile(pathToMLAPP, 'graphic.png');
            app.GraphEVM.Text = 'Graph';
            app.ClearEVM = uibutton(app.GridMultiple, 'push');
            app.ClearEVM.ButtonPushedFcn = createCallbackFcn(app, @ClearEVMPushed, true);
            app.ClearEVM.Icon = fullfile(pathToMLAPP, 'clean.png');
            app.ClearEVM.Text = 'Clear';
            app.GraphMSE = uibutton(app.GridMultiple, 'push');
            app.GraphMSE.ButtonPushedFcn = createCallbackFcn(app, @GraphMSEPushed, true);
            app.GraphMSE.Icon = fullfile(pathToMLAPP, 'graphic.png');
            app.GraphMSE.Text = 'Graph';
            app.ClearMSE = uibutton(app.GridMultiple, 'push');
            app.ClearMSE.ButtonPushedFcn = createCallbackFcn(app, @ClearMSEPushed, true);
            app.ClearMSE.Icon = fullfile(pathToMLAPP, 'clean.png');
            app.ClearMSE.Text = 'Clear';
            app.GraphTIME = uibutton(app.GridMultiple, 'push');
            app.GraphTIME.ButtonPushedFcn = createCallbackFcn(app, @GraphTIMEPushed, true);
            app.GraphTIME.Icon = fullfile(pathToMLAPP, 'graphic.png');
            app.GraphTIME.Text = 'Graph';
            app.ClearTIME = uibutton(app.GridMultiple, 'push');
            app.ClearTIME.ButtonPushedFcn = createCallbackFcn(app, @ClearTIMEPushed, true);
            app.ClearTIME.Icon = fullfile(pathToMLAPP, 'clean.png');
            app.ClearTIME.Text = 'Clear';
            app.NormalizedCheckBox = uicheckbox(app.GridMultiple);
            app.NormalizedCheckBox.Text = 'Normalized';
            app.NormalizedCheckBox.Value = true;
            app.TimeSimulationTab = uitab(app.TabGroup);
            app.TimeSimulationTab.Title = 'Time Simulation';
            app.GridTimeSimulation = uigridlayout(app.TimeSimulationTab);
            app.Titulo_TimeLabel = uilabel(app.GridTimeSimulation);
            app.Titulo_TimeLabel.Text = 'Time Simulation';
            app.GridDistribution2 = uigridlayout(app.GridTimeSimulation);
            app.PanelPlots = uipanel(app.GridDistribution2);
            app.GridPlots = uigridlayout(app.PanelPlots);
            app.Graph1 = uiaxes(app.GridPlots);
            xlabel(app.Graph1, 'Slots')
            ylabel(app.Graph1, 'Subcarriers')
            zlabel(app.Graph1, 'Z')
            app.Graph2 = uiaxes(app.GridPlots);
            xlabel(app.Graph2, 'Slots')
            ylabel(app.Graph2, 'Subcarriers')
            zlabel(app.Graph2, 'Z')
            app.Graph3 = uiaxes(app.GridPlots);
            xlabel(app.Graph3, 'Slots')
            ylabel(app.Graph3, 'Subcarriers')
            zlabel(app.Graph3, 'Z')
            app.PanelActions = uipanel(app.GridDistribution2);
            app.GridPanelActionDistribution = uigridlayout(app.PanelActions);
            app.Symbol = uiaxes(app.GridPanelActionDistribution);
            xlabel(app.Symbol, 'Subcarrier')
            ylabel(app.Symbol, '|H| (dB)')
            zlabel(app.Symbol, 'Z')
            app.GridSymbolInfo = uigridlayout(app.GridPanelActionDistribution);
            app.TimeLabel = uilabel(app.GridSymbolInfo);
            app.TimeLabel.Text = 'OFDM symbol';
            app.EditFieldSymbol = uieditfield(app.GridSymbolInfo, 'numeric');
            app.EditFieldSymbol.Limits = [0 Inf];
            app.EditFieldSymbol.Value = 3;
            app.SymbolsButton = uibutton(app.GridSymbolInfo, 'state');
            app.SymbolsButton.ValueChangedFcn = createCallbackFcn(app, @SymbolsButtonValueChanged, true);
            app.SymbolsButton.Icon = fullfile(pathToMLAPP, 'graphic.png');
            app.SymbolsButton.Text = 'Plot Symbol';
            app.GridInfoDistribution = uigridlayout(app.GridPanelActionDistribution);
            app.GridTimeActions = uigridlayout(app.GridInfoDistribution);
            app.Graph1DropDownLabel = uilabel(app.GridTimeActions);
            app.Graph1DropDownLabel.Text = 'Graph 1';
            app.Grafica1DropDown = uidropdown(app.GridTimeActions);
            app.Grafica1DropDown.Items = {'CNN'};
            app.Grafica1DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica1DropDownValueChanged3, true);
            app.Grafica1DropDown.Value = 'CNN';
            app.Graph2DropDownLabel = uilabel(app.GridTimeActions);
            app.Graph2DropDownLabel.Text = 'Graph 2';
            app.Grafica2DropDown = uidropdown(app.GridTimeActions);
            app.Grafica2DropDown.Items = {'Lineal'};
            app.Grafica2DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica2DropDownValueChanged3, true);
            app.Grafica2DropDown.Value = 'Lineal';
            app.Graph3DropDownLabel = uilabel(app.GridTimeActions);
            app.Graph3DropDownLabel.Text = 'Graph 3';
            app.Grafica3DropDown = uidropdown(app.GridTimeActions);
            app.Grafica3DropDown.Items = {'Practical'};
            app.Grafica3DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica3DropDownValueChanged3, true);
            app.Grafica3DropDown.Value = 'Practical';
            app.ShowTableButton = uibutton(app.GridTimeActions, 'push');
            app.ShowTableButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTableButtonPushed3, true);
            app.ShowTableButton.Icon = fullfile(pathToMLAPP, 'table.png');
            app.ShowTableButton.Text = 'Show Table';
            app.NormalizedTimeCheckBox = uicheckbox(app.GridTimeActions);
            app.NormalizedTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizedTimeCheckBoxValueChanged, true);
            app.NormalizedTimeCheckBox.Text = {'Normalized '; 'Time'};
            app.NormalizedTimeCheckBox.Value = true;
            app.Plot_Constellations = uibutton(app.GridTimeActions, 'push');
            app.Plot_Constellations.ButtonPushedFcn = createCallbackFcn(app, @Plot_ConstellationsButtonPushed, true);
            app.Plot_Constellations.Icon = fullfile(pathToMLAPP, 'const.png');
            app.Plot_Constellations.Text = 'Constellation';
            app.GridTableInfo = uigridlayout(app.GridInfoDistribution);
            app.MSELabel = uilabel(app.GridTableInfo);
            app.MSELabel.Visible = 'off';
            app.MSELabel.Text = 'MSE';
            app.EVMLabel = uilabel(app.GridTableInfo);
            app.EVMLabel.Visible = 'off';
            app.EVMLabel.Text = 'EVM(%)';
            app.TimemsLabel = uilabel(app.GridTableInfo);
            app.TimemsLabel.Visible = 'off';
            app.TimemsLabel.Text = 'Time (ms)';
            app.Model1Label = uilabel(app.GridTableInfo);
            app.Model1Label.Visible = 'off';
            app.Model1Label.Text = 'Model1';
            app.Model2Label = uilabel(app.GridTableInfo);
            app.Model2Label.Visible = 'off';
            app.Model2Label.Text = 'Model2';
            app.Model3Label = uilabel(app.GridTableInfo);
            app.Model3Label.Visible = 'off';
            app.Model3Label.Text = 'Model3';
            app.Model1_MSELabel = uilabel(app.GridTableInfo);
            app.Model1_MSELabel.Visible = 'off';
            app.Model1_MSELabel.Text = 'Model1_MSE';
            app.Model2_MSELabel = uilabel(app.GridTableInfo);
            app.Model2_MSELabel.Visible = 'off';
            app.Model2_MSELabel.Text = 'Model2_MSE';
            app.Model3_MSELabel = uilabel(app.GridTableInfo);
            app.Model3_MSELabel.Visible = 'off';
            app.Model3_MSELabel.Text = 'Model3_MSE';
            app.Model1_EVMLabel = uilabel(app.GridTableInfo);
            app.Model1_EVMLabel.Visible = 'off';
            app.Model1_EVMLabel.Text = 'Model1_EVM';
            app.Model1_TimeLabel = uilabel(app.GridTableInfo);
            app.Model1_TimeLabel.Visible = 'off';
            app.Model1_TimeLabel.Text = 'Model1_Time';
            app.Model2_EVMLabel = uilabel(app.GridTableInfo);
            app.Model2_EVMLabel.Visible = 'off';
            app.Model2_EVMLabel.Text = 'Model2_EVM';
            app.Model3_EVMLabel = uilabel(app.GridTableInfo);
            app.Model3_EVMLabel.Visible = 'off';
            app.Model3_EVMLabel.Text = 'Model3_EVM';
            app.Model2_TimeLabel = uilabel(app.GridTableInfo);
            app.Model2_TimeLabel.Visible = 'off';
            app.Model2_TimeLabel.Text = 'Model2_Time';
            app.Model3_TimeLabel = uilabel(app.GridTableInfo);
            app.Model3_TimeLabel.Visible = 'off';
            app.Model3_TimeLabel.Text = 'Model3_Time';
            app.RealTimeTab = uitab(app.TabGroup);
            app.RealTimeTab.Title = 'Real Time';
            app.GridRealTime = uigridlayout(app.RealTimeTab);
            app.RealTimeChannelVariationLabel = uilabel(app.GridRealTime);
            app.RealTimeChannelVariationLabel.Text = {'Real Time Channel Variation'; ' '};
            app.GridRealTimeDistribution = uigridlayout(app.GridRealTime);
            app.GridPlotRealTime = uigridlayout(app.GridRealTimeDistribution);
            app.Graph1_3 = uiaxes(app.GridPlotRealTime);
            title(app.Graph1_3, 'EVM ')
            xlabel(app.Graph1_3, 'Slot')
            ylabel(app.Graph1_3, 'EVM(%)')
            zlabel(app.Graph1_3, 'Z')
            app.Graph1_4 = uiaxes(app.GridPlotRealTime);
            title(app.Graph1_4, 'Time (ms)')
            xlabel(app.Graph1_4, 'Slot')
            ylabel(app.Graph1_4, 'Estimation Time')
            zlabel(app.Graph1_4, 'Z')
            app.Graph1_2 = uiaxes(app.GridPlotRealTime);
            xlabel(app.Graph1_2, 'OFDM Symbol')
            ylabel(app.Graph1_2, 'Subcarrier')
            zlabel(app.Graph1_2, 'Z')
            app.GridRealTimeActions = uigridlayout(app.GridRealTimeDistribution);
            app.ChannelProfileLabel = uilabel(app.GridRealTimeActions);
            app.ChannelProfileLabel.Text = 'Channel Profile';
            app.DropDown_3 = uidropdown(app.GridRealTimeActions);
            app.DropDown_3.Items = {'TDL-A', 'TDL-B', 'TDL-C', 'TDL-D', 'TDL-E'};
            app.DropDown_3.Value = 'TDL-A';
            app.EstimationTypeDropDownLabel = uilabel(app.GridRealTimeActions);
            app.EstimationTypeDropDownLabel.Text = 'Estimation Type';
            app.EstimationTypeDropDown = uidropdown(app.GridRealTimeActions);
            app.EstimationTypeDropDown.Items = {'Autoencoder', 'CNN', 'Denoising', 'Denoising_2', 'Practical', 'Perfect', 'Lineal'};
            app.EstimationTypeDropDown.ClickedFcn = createCallbackFcn(app, @EstimationTypeDropDownClicked, true);
            app.EstimationTypeDropDown.Value = 'Autoencoder';
            app.CNNModelLabel = uilabel(app.GridRealTimeActions);
            app.CNNModelLabel.Visible = 'off';
            app.CNNModelLabel.Text = 'CNN Model';
            app.CNNModel1DropDown_2 = uidropdown(app.GridRealTimeActions);
            app.CNNModel1DropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.CNNModel1DropDown_2.ValueChangedFcn = createCallbackFcn(app, @CNNModel1DropDown_2ValueChanged, true);
            app.CNNModel1DropDown_2.Visible = 'off';
            app.CNNModel1DropDown_2.Value = '1';
            app.ModulationDropDown_2Label = uilabel(app.GridRealTimeActions);
            app.ModulationDropDown_2Label.Text = 'Modulation';
            app.ModulationDropDown_2 = uidropdown(app.GridRealTimeActions);
            app.ModulationDropDown_2.Items = {'16QAM', '64QAM', '256QAM', 'QPSK'};
            app.ModulationDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModulationDropDown_2ValueChanged, true);
            app.ModulationDropDown_2.Value = '16QAM';
            app.Button_2 = uibutton(app.GridRealTimeActions, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'play.png');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridRealTimeActions, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'stop.png');
            app.Button_3.Text = '';
            app.SNRdBKnob = uiknob(app.GridRealTimeActions, 'continuous');
            app.SNRdBKnob.Limits = [0 40];
            app.SNRdBKnobLabel = uilabel(app.GridRealTimeActions);
            app.SNRdBKnobLabel.Text = 'SNR (dB)';
            app.SpeedkmhKnob = uiknob(app.GridRealTimeActions, 'continuous');
            app.SpeedkmhKnob.Limits = [0 120];
            app.SpeedkmhKnobLabel = uilabel(app.GridRealTimeActions);
            app.SpeedkmhKnobLabel.Text = 'Speed (km/h)';
            app.DatatoplotDropDownLabel = uilabel(app.GridRealTimeActions);
            app.DatatoplotDropDownLabel.Text = 'Data to plot';
            app.DatatoplotDropDown = uidropdown(app.GridRealTimeActions);
            app.DatatoplotDropDown.Items = {'All', 'Last 10', 'Last One'};
            app.DatatoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatoplotDropDownValueChanged, true);
            app.DatatoplotDropDown.ClickedFcn = createCallbackFcn(app, @DatatoplotDropDownClicked, true);
            app.DatatoplotDropDown.Value = 'Last 10';
            app.PhaseCompensation = uicheckbox(app.GridRealTimeActions);
            app.PhaseCompensation.Text = 'Phase Com';
            app.External_plots = uitab(app.TabGroup);
            app.External_plots.Title = 'Extenal Plots';
            % Show the figure after all components are created
            app.DLS5GUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NR5G

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DLS5GUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DLS5GUIFigure)
        end
    end
end